classdef UserInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        EnergyGaugeLabel               matlab.ui.control.Label
        EnergyGauge                    matlab.ui.control.LinearGauge
        DropDownLabel                  matlab.ui.control.Label
        MusicDropDown                  matlab.ui.control.DropDown
        VelocityGaugeLabel             matlab.ui.control.Label
        VelocityGauge                  matlab.ui.control.Gauge
        StartSwitchLabel               matlab.ui.control.Label
        StartSwitch                    matlab.ui.control.ToggleSwitch
        ChangeSongButton               matlab.ui.control.Button
        MusicSwitchLabel               matlab.ui.control.Label
        MusicSwitch                    matlab.ui.control.Switch
        ColisionLampLabel              matlab.ui.control.Label
        ColisionLamp                   matlab.ui.control.Lamp
        GPSFaultSwitchLabel            matlab.ui.control.Label
        GPSFaultSwitch                 matlab.ui.control.Switch
        SpeedometerFaultSwitchLabel    matlab.ui.control.Label
        SpeedometerFaultSwitch         matlab.ui.control.Switch
        EncoderFaultSwitchLabel        matlab.ui.control.Label
        EncoderFaultSwitch             matlab.ui.control.Switch
        TacometerFaultSwitchLabel      matlab.ui.control.Label
        TacometerFaultSwitch           matlab.ui.control.Switch
        PotenciometerFaultSwitchLabel  matlab.ui.control.Label
        PotenciometerFaultSwitch       matlab.ui.control.Switch
        IMUFaultSwitch_6Label          matlab.ui.control.Label
        IMUFaultSwitch                 matlab.ui.control.Switch
    end

    methods (Access = private)

        % Value changed function: StartSwitch
        function StartSwitchValueChanged(app, event)
            disp(app.StartSwitch.Value);
            if (app.StartSwitch.Value == "On")
               app.ColisionLamp.Color = [0 1 0];
               mainFunction(app);
            end
            
        end

        % Button pushed function: ChangeSongButton
        function changeSongButtonPushed(app, event)
            if (app.MusicSwitch.Value)
                clear sound;
                %Musica
                [audio, Fs] = audioread(app.MusicDropDown.Value);
                soundsc(audio,Fs);
            end    
        end

        % Value changed function: MusicSwitch
        function musicOnOff(app, event)
            value = app.MusicSwitch.Value;
            switch app.MusicSwitch.Value
                case 'On'
                    clear sound;
                    [audio, Fs] = audioread(app.MusicDropDown.Value);
                    soundsc(audio,Fs);
                case 'Off'
                    clear sound;
            end
        end

        % Close request function: UIFigure
        function closeCall(app, event)
            clear sound;
            delete(app)    
        end

        % Callback function
        function nextTraj(app, event)

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeCall, true);
            app.EnergyGaugeLabel = uilabel(app.UIFigure);
            app.EnergyGaugeLabel.Text = 'Energy';
            app.EnergyGauge = uigauge(app.UIFigure, 'linear');
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '';
            app.MusicDropDown = uidropdown(app.UIFigure);
            app.MusicDropDown.Items = {'SpaceJam.mp3', 'Rocky.mp3', 'Thunderstruck.mp3', 'Levitating.mp3', 'MarioKartWii.mp3', 'MarioKartDs.mp3'};
            app.MusicDropDown.Value = 'MarioKartDs.mp3';
            app.VelocityGaugeLabel = uilabel(app.UIFigure);
            app.VelocityGaugeLabel.Text = 'Velocity';
            app.VelocityGauge = uigauge(app.UIFigure, 'circular');
            app.StartSwitchLabel = uilabel(app.UIFigure);
            app.StartSwitchLabel.Text = 'Start';
            app.StartSwitch = uiswitch(app.UIFigure, 'toggle');
            app.StartSwitch.ValueChangedFcn = createCallbackFcn(app, @StartSwitchValueChanged, true);
            app.ChangeSongButton = uibutton(app.UIFigure, 'push');
            app.ChangeSongButton.ButtonPushedFcn = createCallbackFcn(app, @changeSongButtonPushed, true);
            app.ChangeSongButton.Text = 'Change Song';
            app.MusicSwitchLabel = uilabel(app.UIFigure);
            app.MusicSwitchLabel.Text = 'Music';
            app.MusicSwitch = uiswitch(app.UIFigure, 'slider');
            app.MusicSwitch.ValueChangedFcn = createCallbackFcn(app, @musicOnOff, true);
            app.ColisionLampLabel = uilabel(app.UIFigure);
            app.ColisionLampLabel.Text = 'Colision';
            app.ColisionLamp = uilamp(app.UIFigure);
            app.GPSFaultSwitchLabel = uilabel(app.UIFigure);
            app.GPSFaultSwitchLabel.Text = 'GPS Fault';
            app.GPSFaultSwitch = uiswitch(app.UIFigure, 'slider');
            app.SpeedometerFaultSwitchLabel = uilabel(app.UIFigure);
            app.SpeedometerFaultSwitchLabel.Text = 'Speedometer Fault';
            app.SpeedometerFaultSwitch = uiswitch(app.UIFigure, 'slider');
            app.EncoderFaultSwitchLabel = uilabel(app.UIFigure);
            app.EncoderFaultSwitchLabel.Text = 'Encoder Fault';
            app.EncoderFaultSwitch = uiswitch(app.UIFigure, 'slider');
            app.TacometerFaultSwitchLabel = uilabel(app.UIFigure);
            app.TacometerFaultSwitchLabel.Text = 'Tacometer Fault';
            app.TacometerFaultSwitch = uiswitch(app.UIFigure, 'slider');
            app.PotenciometerFaultSwitchLabel = uilabel(app.UIFigure);
            app.PotenciometerFaultSwitchLabel.Text = 'Potenciometer Fault';
            app.PotenciometerFaultSwitch = uiswitch(app.UIFigure, 'slider');
            app.IMUFaultSwitch_6Label = uilabel(app.UIFigure);
            app.IMUFaultSwitch_6Label.Text = 'IMU Fault';
            app.IMUFaultSwitch = uiswitch(app.UIFigure, 'slider');
        end
    end

    methods (Access = public)

        % Construct app
        function app = UserInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end